classdef SpectralGranularSinthGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        NormalizeOutputCheckBox         matlab.ui.control.CheckBox
        SourceFileText                  matlab.ui.control.EditField
        SourceFileButton                matlab.ui.control.Button
        OutputFileText                  matlab.ui.control.EditField
        OutputFileButton                matlab.ui.control.Button
        PlayOutputCheckBox              matlab.ui.control.CheckBox
        OutputDurationsecEditFieldLabel  matlab.ui.control.Label
        OutputDurationsecEditField      matlab.ui.control.NumericEditField
        OutputChannelsSpinnerLabel      matlab.ui.control.Label
        OutputChannelsSpinner           matlab.ui.control.Spinner
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        CompTimeVersCheckBox            matlab.ui.control.CheckBox
        InputGrainSizesmpEditFieldLabel  matlab.ui.control.Label
        InputGrainSizesmpEditField      matlab.ui.control.NumericEditField
        OutputGrainSizesmpEditFieldLabel  matlab.ui.control.Label
        OutputGrainSizesmpEditField     matlab.ui.control.NumericEditField
        GrainOverlapsmpEditFieldLabel   matlab.ui.control.Label
        GrainOverlapsmpEditField        matlab.ui.control.NumericEditField
        PGHIGammaFastCheckBox           matlab.ui.control.CheckBox
        WindowTypeDropDownLabel         matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        KaiserBetaEditFieldLabel        matlab.ui.control.Label
        KaiserBetaEditField             matlab.ui.control.NumericEditField
        PhaseReconAlgoDropDownLabel     matlab.ui.control.Label
        PhaseReconAlgoDropDown          matlab.ui.control.DropDown
        PGHIThresholdEditFieldLabel     matlab.ui.control.Label
        PGHIThresholdEditField          matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        GrainExtractionDropDownLabel    matlab.ui.control.Label
        GrainExtractionDropDown         matlab.ui.control.DropDown
        FilePositionSliderLabel         matlab.ui.control.Label
        FilePositionSlider              matlab.ui.control.Slider
        DirectionDropDownLabel          matlab.ui.control.Label
        DirectionDropDown               matlab.ui.control.DropDown
        PosRandDevEditFieldLabel        matlab.ui.control.Label
        PosRandDevEditField             matlab.ui.control.NumericEditField
        PosModAmpEditFieldLabel         matlab.ui.control.Label
        PosModAmpEditField              matlab.ui.control.NumericEditField
        PosModFreqEditFieldLabel        matlab.ui.control.Label
        PosModFreqEditField             matlab.ui.control.NumericEditField
        SeqSpeedEditFieldLabel          matlab.ui.control.Label
        SeqSpeedEditField               matlab.ui.control.NumericEditField
        PlotsCheckBox                   matlab.ui.control.CheckBox
        SizeVarAmountEditFieldLabel     matlab.ui.control.Label
        SizeVarAmountEditField          matlab.ui.control.NumericEditField
        SizeVarFreqEditFieldLabel       matlab.ui.control.Label
        SizeVarFreqEditField            matlab.ui.control.NumericEditField
        SpectShAmountEditFieldLabel     matlab.ui.control.Label
        SpectShAmountEditField          matlab.ui.control.NumericEditField
        SpectShModFreqEditFieldLabel    matlab.ui.control.Label
        SpectShModFreqEditField         matlab.ui.control.NumericEditField
        InGrainSizeVariaionDropDownLabel  matlab.ui.control.Label
        InGrainSizeVariaionDropDown     matlab.ui.control.DropDown
        SpectralGranularSynthesisLabel  matlab.ui.control.Label
        httpstefanofascianicomsgshtmlLabel  matlab.ui.control.Label
        SpectShiftModeDropDownLabel     matlab.ui.control.Label
        SpectShiftModeDropDown          matlab.ui.control.DropDown
    end

% Spectral Granular Synthesis
% (C) 2018 Stefano Fasciani, University of Wollongong in Dubai
% Inquiries: stefanofasciani@stefanofasciani.com
% 
% The Spectral Granular Synthesis software can be obtained at
% http://stefanofasciani.com/sgs.html
% 
% This Spectral Granular Synthesis is free software: 
% you can redistribute it and/or modify it under the terms of the
% GNU General Public License as published by the Free Software Foundation,
% either version 3 of the License, or (at your option) any later version.
% 
% This program is distributed in the hope that it will be useful,
% but WITHOUT ANY WARRANTY; without even the implied warranty of
% MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
% GNU General Public License for more details.
% 
% You should have received a copy of the GNU General Public License
% along with this program. If not, see <http://www.gnu.org/licenses/>.
% 
% If you use the Spectral Granular Synthesis or any part of it in any system
% or publication, please acknowledge its author by adding a reference 
% to this pubblication:
% 
% S. Fasciani, "Spectral Granular Synthesis" in proceedings of
% International Computer Music Conference 2018, Daegu, Korea.

    properties (Access = public)
        %default values
        Fs_sys = 48000; %System sampling rate Hz
        fileIn = ''; % input sound file
        fileOut = ''; %'out.wav'; % output sound file, leave empty if for no output file generation
        normalize = 1; %if 1 output is normalized to max amplitude 1
        playback = 1; % if equal to 1 playback the sound at the end of the synthesis loop
        plots = 0; % if equal to 1 display signal plots and window shape
        duration_sec = 5; %length of output signal in seconds (any value above 0s)
        outChannels = 1; %1 is mono, 2 is stereo, etc...
        compTimeGran = 0; %if 1 it computes the time domain granulation equivalent as well
        grainSizeIn_smp = 2048; %size of grain in samples extracted from source (any value above 0 and below the lenght of the sound file in samples), better if even
        grainSizeOut_smp = 2048; %size of grain in samples sequenced for synthesis (any value above 0 and below the lenght of the sound file in samples), must be even, better if power of 2
        grainOverlap_smp = 1024; %overlap between consecutive grains in samples (any value between 0 and grainSizeIn_smp-1), better if even
    
        windowType = 3; %defines window type, 0 hanning, 1 hamming, 2 blackman, 3 kaiser
        kaiserBeta = 7; %defines the window shape of kaiser window only, with 0 is retangular
        gammaFast = 0; % 0 computes more accurate gamma for RTPGHI slow, 1 computes less accurate gamma for RTPGHI but fast (computation once only, out of synthesis loop)
    
        inversionAlgo = 1; %algorithm for spectrum inversion, 0 SPSI, 1 RTPGHI
        thresholdRtphi = 6; %Threshold of RTPGHI 10^-(thresholdRtphi), default is 6
    
        grainSelMode = 1; % 1 = fixed position + random spray; 2 = sequential + random spray; 3 = random;
        filePos_perc = 0.1; % file position - percentage between 0 and 1 - to extract grains (mode 1 and 2 only)
        spray_perc = 0.3; % random deviation in percentage between 0 and 1 from the fixed position to extract grains (mode 1 and 2 only)
        sprayMode = 1; % 1 backward and forward, 2 backward only, 3 forward only (mode 1 and 2 only)
        sequenceRate = 0.5; % grain selection rate (positive or negative) with respect grain size (mode 2 only)
        filePosModAmp = 0;% amplitude of file position modulation - percentage between 0 and 1
        filePosModFreq = 0; % frequency of file position modulation 
    
        grainSizeInVarMode = 1; % 0 no modulation, 1 random, 2 sinusoidal modulated 
        grainSizeVarAmp = 0; % percentage of random deviation of the grain size (percentage of grainSizeIn_smp)
        grainSizeVarFreq = 0; % frequency of modulation (only for sinusoidal modulated) 
    
        freqShiftVarMode = 0; % 0 fixed , 1 random, 2 sinusoidal modulated - variation of shift of FFT bins 0 to 1,
        freqShiftVarAmp = 0; % percentage of random deviation of the grain size -1 fo 1 (percentage of grainSizeIn_smp)
        freqShiftVarFreq = 0; % frequency of modulation (only for sinusoidal modulated) 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SamplingRateEditField.Value = app.Fs_sys;
            app.NormalizeOutputCheckBox.Value = app.normalize;
            app.SourceFileText.Value = app.fileIn;
            app.OutputFileText.Value = app.fileOut;
            app.PlayOutputCheckBox.Value = app.playback;
            app.OutputDurationsecEditField.Value = app.duration_sec;
            app.OutputChannelsSpinner.Value = app.outChannels;
            app.InputGrainSizesmpEditField.Value = app.grainSizeIn_smp;
            app.OutputGrainSizesmpEditField.Value = app.grainSizeOut_smp;
            app.GrainOverlapsmpEditField.Value = app.grainOverlap_smp;
            app.PGHIGammaFastCheckBox.Value = app.gammaFast;
            app.WindowTypeDropDown.Value = num2str(app.windowType);
            app.KaiserBetaEditField.Value = app.kaiserBeta;
            app.PhaseReconAlgoDropDown.Value = num2str(app.inversionAlgo);
            app.PGHIThresholdEditField.Value = app.thresholdRtphi;
            app.GrainExtractionDropDown.Value = num2str(app.grainSelMode);
            app.FilePositionSlider.Value = app.filePos_perc;
            app.DirectionDropDown.Value = num2str(app.sprayMode);
            app.PosRandDevEditField.Value = app.spray_perc;
            app.PosModAmpEditField.Value = app.filePosModAmp;
            app.PosModFreqEditField.Value = app.filePosModFreq;
            app.SeqSpeedEditField.Value = app.sequenceRate;
            app.PlotsCheckBox.Value = app.plots;
            app.InGrainSizeVariaionDropDown.Value = num2str(app.grainSizeInVarMode);
            app.SpectShiftModeDropDown.Value = num2str(app.freqShiftVarMode);
            app.SizeVarAmountEditField.Value = app.grainSizeVarAmp;
            app.SizeVarFreqEditField.Value = app.grainSizeVarFreq;
            app.SpectShAmountEditField.Value = app.freqShiftVarAmp;
            app.SpectShModFreqEditField.Value = app.freqShiftVarFreq;
            app.SpectShModFreqEditField.Enable = 'off';
            app.SeqSpeedEditField.Enable = 'off';
            app.PGHIGammaFastCheckBox.Enable = 'on';
            app.PGHIThresholdEditField.Enable = 'on';
            app.SizeVarAmountEditField.Enable = 'off';
            app.SizeVarFreqEditField.Enable = 'off';
            app.SpectShModFreqEditField.Enable = 'off';
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            app.Fs_sys = app.SamplingRateEditField.Value;
        end

        % Value changed function: NormalizeOutputCheckBox
        function NormalizeOutputCheckBoxValueChanged(app, event)
            app.normalize = app.NormalizeOutputCheckBox.Value;
        end

        % Button pushed function: SourceFileButton
        function SourceFileButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.*',  'All Files (*.*)'});
            app.fileIn = [pathname, filename];
            app.SourceFileText.Value = app.fileIn;
        end

        % Button pushed function: OutputFileButton
        function OutputFileButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.*',  'All Files (*.*)'});
            app.fileOut = [pathname, filename];
            app.OutputFileText.Value = app.fileOut;     
        end

        % Value changed function: PlayOutputCheckBox
        function PlayOutputCheckBoxValueChanged(app, event)
            app.playback = app.PlayOutputCheckBox.Value;
        end

        % Value changed function: OutputDurationsecEditField
        function OutputDurationsecEditFieldValueChanged(app, event)
            app.duration_sec = app.OutputDurationsecEditField.Value;
        end

        % Value changed function: OutputChannelsSpinner
        function OutputChannelsSpinnerValueChanged(app, event)
            app.outChannels = app.OutputChannelsSpinner.Value;
        end

        % Value changed function: InputGrainSizesmpEditField
        function InputGrainSizesmpEditFieldValueChanged(app, event)
            app.grainSizeIn_smp = app.InputGrainSizesmpEditField.Value;
            if (rem(app.grainSizeIn_smp,2)~=0)
                app.grainSizeIn_smp = app.grainSizeIn_smp + 1;
            end
            app.InputGrainSizesmpEditField.Value = app.grainSizeIn_smp;
        end

        % Value changed function: OutputGrainSizesmpEditField
        function OutputGrainSizesmpEditFieldValueChanged(app, event)
            app.grainSizeOut_smp = app.OutputGrainSizesmpEditField.Value;
            if (rem(app.grainSizeOut_smp,2)~=0)
                app.grainSizeOut_smp = app.grainSizeOut_smp + 1;
                app.OutputGrainSizesmpEditField.Value = app.grainSizeOut_smp;
            end
        end

        % Value changed function: GrainOverlapsmpEditField
        function GrainOverlapsmpEditFieldValueChanged(app, event)
            app.grainOverlap_smp = app.GrainOverlapsmpEditField.Value;
            if (rem(app.grainOverlap_smp,2)~=0)
                app.grainOverlap_smp = app.grainOverlap_smp + 1;
                app.GrainOverlapsmpEditField.Value = app.grainOverlap_smp;
            end
        end

        % Value changed function: PGHIGammaFastCheckBox
        function PGHIGammaFastCheckBoxValueChanged(app, event)
            app.gammaFast = app.PGHIGammaFastCheckBox.Value;
        end

        % Value changed function: WindowTypeDropDown
        function WindowTypeDropDownValueChanged(app, event)
            app.windowType = str2double(app.WindowTypeDropDown.Value);
            if app.windowType == 3
                app.KaiserBetaEditField.Enable = 'on';
            else
                app.KaiserBetaEditField.Enable = 'off';
            end
        end

        % Value changed function: KaiserBetaEditField
        function KaiserBetaEditFieldValueChanged(app, event)
            app.kaiserBeta = app.KaiserBetaEditField.Value;
        end

        % Value changed function: PhaseReconAlgoDropDown
        function PhaseReconAlgoDropDownValueChanged(app, event)
            app.inversionAlgo = str2double(app.PhaseReconAlgoDropDown.Value);
            if app.inversionAlgo == 1
                app.PGHIThresholdEditField.Enable = 'on';
                app.PGHIGammaFastCheckBox.Enable = 'on';
            else
                app.PGHIThresholdEditField.Enable = 'off';
                app.PGHIGammaFastCheckBox.Enable = 'off';
            end
        end

        % Value changed function: PGHIThresholdEditField
        function PGHIThresholdEditFieldValueChanged(app, event)
            app.thresholdRtphi = app.PGHIThresholdEditField.Value;
        end

        % Value changed function: GrainExtractionDropDown
        function GrainExtractionDropDownValueChanged(app, event)
            app.grainSelMode = str2double(app.GrainExtractionDropDown.Value);
            if app.grainSelMode == 3
                app.FilePositionSlider.Enable = 'off';
                app.DirectionDropDown.Enable = 'off';
                app.PosRandDevEditField.Enable = 'off';
                app.PosModAmpEditField.Enable = 'off';
                app.PosModFreqEditField.Enable = 'off';
                app.SeqSpeedEditField.Enable = 'off';
            elseif app.grainSelMode == 2
                app.FilePositionSlider.Enable = 'on';
                app.DirectionDropDown.Enable = 'on';
                app.PosRandDevEditField.Enable = 'on';
                app.PosModAmpEditField.Enable = 'on';
                app.PosModFreqEditField.Enable = 'on';
                app.SeqSpeedEditField.Enable = 'on';
            elseif app.grainSelMode == 1
                app.FilePositionSlider.Enable = 'on';
                app.DirectionDropDown.Enable = 'on';
                app.PosRandDevEditField.Enable = 'on';
                app.PosModAmpEditField.Enable = 'on';
                app.PosModFreqEditField.Enable = 'on';
                app.SeqSpeedEditField.Enable = 'off';
            end
        end

        % Value changed function: FilePositionSlider
        function FilePositionSliderValueChanged(app, event)
            app.filePos_perc = app.FilePositionSlider.Value;
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            app.sprayMode = str2double(app.DirectionDropDown.Value);
        end

        % Value changed function: PosRandDevEditField
        function PosRandDevEditFieldValueChanged(app, event)
            app.spray_perc = app.PosRandDevEditField.Value;  
        end

        % Value changed function: PosModAmpEditField
        function PosModAmpEditFieldValueChanged(app, event)
            app.filePosModAmp = app.PosModAmpEditField.Value;
        end

        % Value changed function: PosModFreqEditField
        function PosModFreqEditFieldValueChanged(app, event)
            app.filePosModFreq = app.PosModFreqEditField.Value;
        end

        % Value changed function: SeqSpeedEditField
        function SeqSpeedEditFieldValueChanged(app, event)
            app.sequenceRate = app.SeqSpeedEditField.Value;     
        end

        % Value changed function: PlotsCheckBox
        function PlotsCheckBoxValueChanged(app, event)
            app.plots = app.PlotsCheckBox.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ReadyLamp.Color = [0.85 0 0];
            app.ReadyLampLabel.Text = 'Running...';
            SpectralGranularSinth;
            app.ReadyLamp.Color = [0 0.85 0];
            app.ReadyLampLabel.Text = 'Ready';
        end

        % Value changed function: InGrainSizeVariaionDropDown
        function InGrainSizeVariaionDropDownValueChanged(app, event)
            app.grainSizeInVarMode = str2double(app.InGrainSizeVariaionDropDown.Value);
            if  app.grainSizeInVarMode == 2
                app.SizeVarAmountEditField.Enable = 'on';
                app.SizeVarFreqEditField.Enable = 'on';
            elseif app.grainSizeInVarMode == 1
                app.SizeVarAmountEditField.Enable = 'on';
                app.SizeVarFreqEditField.Enable = 'off';
            elseif app.grainSizeInVarMode == 0
                app.SizeVarAmountEditField.Enable = 'off';
                app.SizeVarFreqEditField.Enable = 'off';
            end
        end

        % Value changed function: SpectShiftModeDropDown
        function SpectShiftModeDropDownValueChanged(app, event)
            app.freqShiftVarMode = str2double(app.SpectShiftModeDropDown.Value);
            if  app.freqShiftVarMode == 2
                app.SpectShModFreqEditField.Enable = 'on';
            else
                app.SpectShModFreqEditField.Enable = 'off';
            end
        end

        % Value changed function: SizeVarAmountEditField
        function SizeVarAmountEditFieldValueChanged(app, event)
            app.grainSizeVarAmp = app.SizeVarAmountEditField.Value;            
        end

        % Value changed function: SizeVarFreqEditField
        function SizeVarFreqEditFieldValueChanged(app, event)
            app.grainSizeVarFreq = app.SizeVarFreqEditField.Value;          
        end

        % Value changed function: SpectShAmountEditField
        function SpectShAmountEditFieldValueChanged(app, event)
            app.freqShiftVarAmp = app.SpectShAmountEditField.Value;            
        end

        % Value changed function: SpectShModFreqEditField
        function SpectShModFreqEditFieldValueChanged(app, event)
            app.freqShiftVarFreq = app.SpectShModFreqEditField.Value;
        end

        % Value changed function: CompTimeVersCheckBox
        function CompTimeVersCheckBoxValueChanged(app, event)
            app.compTimeGran = app.CompTimeVersCheckBox.Value;
        end

        % Value changed function: SourceFileText
        function SourceFileTextValueChanged(app, event)
            app.fileIn = app.SourceFileText.Value;
        end

        % Value changed function: OutputFileText
        function OutputFileTextValueChanged(app, event)
            app.fileOut = app.OutputFileText.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField.Limits = [1000 192000];
            app.SamplingRateEditField.ValueDisplayFormat = '%.0f';
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 48000;
            app.NormalizeOutputCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeOutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeOutputCheckBoxValueChanged, true);
            app.NormalizeOutputCheckBox.Text = 'Normalize Output';
            app.SourceFileText = uieditfield(app.UIFigure, 'text');
            app.SourceFileText.ValueChangedFcn = createCallbackFcn(app, @SourceFileTextValueChanged, true);
            app.SourceFileButton = uibutton(app.UIFigure, 'push');
            app.SourceFileButton.ButtonPushedFcn = createCallbackFcn(app, @SourceFileButtonPushed, true);
            app.SourceFileButton.Text = 'Source File';
            app.OutputFileText = uieditfield(app.UIFigure, 'text');
            app.OutputFileText.ValueChangedFcn = createCallbackFcn(app, @OutputFileTextValueChanged, true);
            app.OutputFileButton = uibutton(app.UIFigure, 'push');
            app.OutputFileButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFileButtonPushed, true);
            app.OutputFileButton.Text = 'Output File';
            app.PlayOutputCheckBox = uicheckbox(app.UIFigure);
            app.PlayOutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlayOutputCheckBoxValueChanged, true);
            app.PlayOutputCheckBox.Text = 'Play Output';
            app.OutputDurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.OutputDurationsecEditFieldLabel.Text = 'Output Duration (sec)';
            app.OutputDurationsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputDurationsecEditField.Limits = [0 Inf];
            app.OutputDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @OutputDurationsecEditFieldValueChanged, true);
            app.OutputChannelsSpinnerLabel = uilabel(app.UIFigure);
            app.OutputChannelsSpinnerLabel.Text = 'Output Channels';
            app.OutputChannelsSpinner = uispinner(app.UIFigure);
            app.OutputChannelsSpinner.Limits = [1 Inf];
            app.OutputChannelsSpinner.ValueChangedFcn = createCallbackFcn(app, @OutputChannelsSpinnerValueChanged, true);
            app.OutputChannelsSpinner.Value = 1;
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.CompTimeVersCheckBox = uicheckbox(app.UIFigure);
            app.CompTimeVersCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompTimeVersCheckBoxValueChanged, true);
            app.CompTimeVersCheckBox.Text = 'Comp Time Vers.';
            app.InputGrainSizesmpEditFieldLabel = uilabel(app.UIFigure);
            app.InputGrainSizesmpEditFieldLabel.Text = 'Input Grain Size (smp)';
            app.InputGrainSizesmpEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputGrainSizesmpEditField.ValueDisplayFormat = '%.0f';
            app.InputGrainSizesmpEditField.ValueChangedFcn = createCallbackFcn(app, @InputGrainSizesmpEditFieldValueChanged, true);
            app.OutputGrainSizesmpEditFieldLabel = uilabel(app.UIFigure);
            app.OutputGrainSizesmpEditFieldLabel.Text = 'Output Grain Size (smp)';
            app.OutputGrainSizesmpEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputGrainSizesmpEditField.Limits = [2 Inf];
            app.OutputGrainSizesmpEditField.ValueDisplayFormat = '%.0f';
            app.OutputGrainSizesmpEditField.ValueChangedFcn = createCallbackFcn(app, @OutputGrainSizesmpEditFieldValueChanged, true);
            app.OutputGrainSizesmpEditField.Value = 2;
            app.GrainOverlapsmpEditFieldLabel = uilabel(app.UIFigure);
            app.GrainOverlapsmpEditFieldLabel.Text = 'Grain Overlap (smp)';
            app.GrainOverlapsmpEditField = uieditfield(app.UIFigure, 'numeric');
            app.GrainOverlapsmpEditField.Limits = [0 Inf];
            app.GrainOverlapsmpEditField.ValueDisplayFormat = '%.0f';
            app.GrainOverlapsmpEditField.ValueChangedFcn = createCallbackFcn(app, @GrainOverlapsmpEditFieldValueChanged, true);
            app.GrainOverlapsmpEditField.Value = 2;
            app.PGHIGammaFastCheckBox = uicheckbox(app.UIFigure);
            app.PGHIGammaFastCheckBox.ValueChangedFcn = createCallbackFcn(app, @PGHIGammaFastCheckBoxValueChanged, true);
            app.PGHIGammaFastCheckBox.Text = 'PGHI Gamma Fast';
            app.WindowTypeDropDownLabel = uilabel(app.UIFigure);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.UIFigure);
            app.WindowTypeDropDown.Items = {'Hanning', 'Hamming', 'Blackman', 'Kaiser'};
            app.WindowTypeDropDown.ItemsData = {'0', '1', '2', '3'};
            app.WindowTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowTypeDropDownValueChanged, true);
            app.WindowTypeDropDown.Value = '3';
            app.KaiserBetaEditFieldLabel = uilabel(app.UIFigure);
            app.KaiserBetaEditFieldLabel.Text = 'Kaiser Beta';
            app.KaiserBetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.KaiserBetaEditField.Limits = [0 Inf];
            app.KaiserBetaEditField.ValueChangedFcn = createCallbackFcn(app, @KaiserBetaEditFieldValueChanged, true);
            app.PhaseReconAlgoDropDownLabel = uilabel(app.UIFigure);
            app.PhaseReconAlgoDropDownLabel.Text = 'Phase Recon. Algo.';
            app.PhaseReconAlgoDropDown = uidropdown(app.UIFigure);
            app.PhaseReconAlgoDropDown.Items = {'SPSI', 'PGHI', 'Off'};
            app.PhaseReconAlgoDropDown.ItemsData = {'0', '1', '2'};
            app.PhaseReconAlgoDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseReconAlgoDropDownValueChanged, true);
            app.PhaseReconAlgoDropDown.Value = '0';
            app.PGHIThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.PGHIThresholdEditFieldLabel.Text = 'PGHI Threshold';
            app.PGHIThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.PGHIThresholdEditField.Limits = [0 Inf];
            app.PGHIThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PGHIThresholdEditFieldValueChanged, true);
            app.PGHIThresholdEditField.Value = 6;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GrainExtractionDropDownLabel = uilabel(app.UIFigure);
            app.GrainExtractionDropDownLabel.Text = 'Grain Extraction';
            app.GrainExtractionDropDown = uidropdown(app.UIFigure);
            app.GrainExtractionDropDown.Items = {'Fixed', 'Sequential', 'Random'};
            app.GrainExtractionDropDown.ItemsData = {'1', '2', '3'};
            app.GrainExtractionDropDown.ValueChangedFcn = createCallbackFcn(app, @GrainExtractionDropDownValueChanged, true);
            app.GrainExtractionDropDown.Value = '1';
            app.FilePositionSliderLabel = uilabel(app.UIFigure);
            app.FilePositionSliderLabel.Text = 'File Position';
            app.FilePositionSlider = uislider(app.UIFigure);
            app.FilePositionSlider.Limits = [0 1];
            app.FilePositionSlider.MajorTicks = [0 0.001 0.002 0.003 0.004 0.005 0.006 0.007 0.008 0.009 0.01 0.011 0.012 0.013 0.014 0.015 0.016 0.017 0.018 0.019 0.02 0.021 0.022 0.023 0.024 0.025 0.026 0.027 0.028 0.029];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
            app.FilePositionSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.FilePositionSlider.ValueChangedFcn = createCallbackFcn(app, @FilePositionSliderValueChanged, true);
            app.FilePositionSlider.MinorTicks = [];
            app.DirectionDropDownLabel = uilabel(app.UIFigure);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.UIFigure);
            app.DirectionDropDown.Items = {'Back & Fwd', 'Backward', 'Forward', 'Option 4'};
            app.DirectionDropDown.ItemsData = {'1', '2', '3'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = '1';
            app.PosRandDevEditFieldLabel = uilabel(app.UIFigure);
            app.PosRandDevEditFieldLabel.Text = 'Pos. Rand. Dev.';
            app.PosRandDevEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosRandDevEditField.Limits = [0 1];
            app.PosRandDevEditField.ValueChangedFcn = createCallbackFcn(app, @PosRandDevEditFieldValueChanged, true);
            app.PosModAmpEditFieldLabel = uilabel(app.UIFigure);
            app.PosModAmpEditFieldLabel.Text = 'Pos. Mod. Amp.';
            app.PosModAmpEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosModAmpEditField.Limits = [0 1];
            app.PosModAmpEditField.ValueChangedFcn = createCallbackFcn(app, @PosModAmpEditFieldValueChanged, true);
            app.PosModFreqEditFieldLabel = uilabel(app.UIFigure);
            app.PosModFreqEditFieldLabel.Text = 'Pos. Mod. Freq.';
            app.PosModFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosModFreqEditField.Limits = [0 Inf];
            app.PosModFreqEditField.ValueChangedFcn = createCallbackFcn(app, @PosModFreqEditFieldValueChanged, true);
            app.SeqSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeqSpeedEditFieldLabel.Text = 'Seq. Speed';
            app.SeqSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeqSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SeqSpeedEditFieldValueChanged, true);
            app.SeqSpeedEditField.Value = 1;
            app.PlotsCheckBox = uicheckbox(app.UIFigure);
            app.PlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotsCheckBoxValueChanged, true);
            app.PlotsCheckBox.Text = 'Plots';
            app.SizeVarAmountEditFieldLabel = uilabel(app.UIFigure);
            app.SizeVarAmountEditFieldLabel.Text = 'Size Var. Amount';
            app.SizeVarAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeVarAmountEditField.Limits = [0 1];
            app.SizeVarAmountEditField.ValueChangedFcn = createCallbackFcn(app, @SizeVarAmountEditFieldValueChanged, true);
            app.SizeVarFreqEditFieldLabel = uilabel(app.UIFigure);
            app.SizeVarFreqEditFieldLabel.Text = 'Size Var. Freq.';
            app.SizeVarFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeVarFreqEditField.Limits = [0 Inf];
            app.SizeVarFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SizeVarFreqEditFieldValueChanged, true);
            app.SpectShAmountEditFieldLabel = uilabel(app.UIFigure);
            app.SpectShAmountEditFieldLabel.Text = 'Spect. Sh. Amount';
            app.SpectShAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectShAmountEditField.Limits = [-1 1];
            app.SpectShAmountEditField.ValueChangedFcn = createCallbackFcn(app, @SpectShAmountEditFieldValueChanged, true);
            app.SpectShModFreqEditFieldLabel = uilabel(app.UIFigure);
            app.SpectShModFreqEditFieldLabel.Text = 'Spect. Sh. Mod. Freq.';
            app.SpectShModFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectShModFreqEditField.Limits = [0 Inf];
            app.SpectShModFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SpectShModFreqEditFieldValueChanged, true);
            app.InGrainSizeVariaionDropDownLabel = uilabel(app.UIFigure);
            app.InGrainSizeVariaionDropDownLabel.Text = 'In Grain Size Variaion';
            app.InGrainSizeVariaionDropDown = uidropdown(app.UIFigure);
            app.InGrainSizeVariaionDropDown.Items = {'Off', 'Random', 'Modulated', 'Option 4'};
            app.InGrainSizeVariaionDropDown.ItemsData = {'0', '1', '2'};
            app.InGrainSizeVariaionDropDown.ValueChangedFcn = createCallbackFcn(app, @InGrainSizeVariaionDropDownValueChanged, true);
            app.InGrainSizeVariaionDropDown.Value = '0';
            app.SpectralGranularSynthesisLabel = uilabel(app.UIFigure);
            app.SpectralGranularSynthesisLabel.Text = 'Spectral Granular Synthesis';
            app.httpstefanofascianicomsgshtmlLabel = uilabel(app.UIFigure);
            app.httpstefanofascianicomsgshtmlLabel.Text = 'http://stefanofasciani.com/sgs.html';
            app.SpectShiftModeDropDownLabel = uilabel(app.UIFigure);
            app.SpectShiftModeDropDownLabel.Text = 'Spect. Shift Mode';
            app.SpectShiftModeDropDown = uidropdown(app.UIFigure);
            app.SpectShiftModeDropDown.Items = {'Fixed', 'Random', 'Modulated', 'Option 4'};
            app.SpectShiftModeDropDown.ItemsData = {'0', '1', '2'};
            app.SpectShiftModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectShiftModeDropDownValueChanged, true);
            app.SpectShiftModeDropDown.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpectralGranularSinthGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end